classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptionsPanel                    matlab.ui.container.Panel
        UsegpuArrayforHardwareAccelerationSwitchLabel  matlab.ui.control.Label
        gpuArraySwitch                  matlab.ui.control.Switch
        SelectImageButton               matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        NoisePanel                      matlab.ui.container.Panel
        SpeckleNoiseSliderLabel         matlab.ui.control.Label
        SpeckleNoiseSlider              matlab.ui.control.Slider
        SaltPepperNoiseSliderLabel      matlab.ui.control.Label
        SaltPepperNoiseSlider           matlab.ui.control.Slider
        WhiteUncorrelatedNoiseLabel     matlab.ui.control.Label
        WhiteNoiseSlider                matlab.ui.control.Slider
        PinkElectronicFlickerNoiseSliderLabel  matlab.ui.control.Label
        FlickerNoiseSlider              matlab.ui.control.Slider
        PoissonNoiseSwitchLabel         matlab.ui.control.Label
        PoissonNoiseSwitch              matlab.ui.control.Switch
        ImagePanel                      matlab.ui.container.Panel
        UIAxes_Original                 matlab.ui.control.UIAxes
        UIAxes_Noisy                    matlab.ui.control.UIAxes
        UIAxes_Filtered                 matlab.ui.control.UIAxes
        UIAxes_Detected                 matlab.ui.control.UIAxes
        UIAxes_Ideal                    matlab.ui.control.UIAxes
        FilteringPanel                  matlab.ui.container.Panel
        MedianFilterSliderLabel         matlab.ui.control.Label
        MedianFilterSlider              matlab.ui.control.Slider
        GaussianBlurSliderLabel         matlab.ui.control.Label
        GaussianBlurSlider              matlab.ui.control.Slider
        FeatureDetectPanel              matlab.ui.container.Panel
        EdgeKernelsButtonGroup          matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        KernelConvolutionButton         matlab.ui.control.RadioButton
        CannyFilterButton               matlab.ui.control.RadioButton
        SobelFilterButton               matlab.ui.control.RadioButton
        LogarithmicContrastSliderLabel  matlab.ui.control.Label
        LogContrastSlider               matlab.ui.control.Slider
        LinearContrastSliderLabel       matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
    end


    properties (Access = private)
        %Property       Description
        Original_Image
        Noisy_Image
        Filtered_Image
        Detected_Image
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [file,~]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file'); %formerly file,path
            app.Original_Image = rgb2gray(imread(file));
            app.Noisy_Image = rgb2gray(imread(file));
            app.Filtered_Image = rgb2gray(imread(file));
            app.Detected_Image = rgb2gray(imread(file));
            imshow(app.Original_Image, 'parent', app.UIAxes_Original);
            imshow(app.Noisy_Image, 'parent', app.UIAxes_Noisy);
            imshow(app.Filtered_Image, 'parent', app.UIAxes_Filtered);
            imshow(app.Detected_Image, 'parent', app.UIAxes_Detected);
            imshow(app.Original_Image, 'parent', app.UIAxes_Ideal);


        end

        % Callback function: ContrastSlider, EdgeKernelsButtonGroup, 
        % FlickerNoiseSlider, GaussianBlurSlider, LogContrastSlider, 
        % MedianFilterSlider, PoissonNoiseSwitch, RefreshButton, 
        % SaltPepperNoiseSlider, SpeckleNoiseSlider, WhiteNoiseSlider
        function ImageRefreshFcn(app, event)
            %position = app.UIFigure.Position;
            imshow(app.Original_Image, 'parent', app.UIAxes_Original);
            
            original = app.Original_Image;

            %set CUDA mode
            if (app.gpuArraySwitch == 'On')
                original = gpuArray(original);
            else 
                original = app.Original_Image;

            end
            
            noisy = original;

            
            %Apply white noise
            noisy = imnoise(noisy,'gaussian',0,app.WhiteNoiseSlider.Value);
            
            %Apply Speckle Noise
            noisy = imnoise(noisy,'speckle',app.SpeckleNoiseSlider.Value);
            
            %Apply Salt and Pepper noise
            noisy = imnoise(noisy,'salt & pepper',app.SaltPepperNoiseSlider.Value);

            %Apply Flicker noise
            noisy = imflicker(noisy,app.FlickerNoiseSlider.Value);
            
            %Apply Poisson Noise
            if (app.PoissonNoiseSwitch == 'On')
                noisy = imnoise(noisy,'poisson');
            end
            
            %Show the image
            app.Noisy_Image = noisy;
            imshow(app.Noisy_Image, 'parent', app.UIAxes_Noisy);
            
            filtered = noisy;
            
            %Apply the median filter
            z = app.MedianFilterSlider.Value;
            z = floor(z);
            filtered = medfilt2(filtered, [z z]);
            
            %Apply the gaussian blur
            sigma = app.GaussianBlurSlider.Value;
            if sigma > 0
                filtered = imgaussfilt(noisy,sigma);
            end
            
            
            %Show the image
            app.Filtered_Image = filtered;
            imshow(app.Filtered_Image, 'parent', app.UIAxes_Filtered);
            
            detect = filtered;
            
            %Apply linear contrast stretch
            B = app.ContrastSlider.Value;
            if B > 0
                B = 200 - B; %Take the complement of B so that it increases logically
                detect = uint8(detect);
                one = uint8(ones(size(detect)));
                c = 800;
                E = 4;
                detect = c./(one + B./(detect).*E);
            end
            
            %Apply Logarithmic Contrast
            A = app.LogContrastSlider.Value;
            if A > 0
                detect = (0.1*log(exp(1) + A.* double(detect)));
            end
            
            switch app.EdgeKernelsButtonGroup.SelectedObject
                case app.OffButton
                    % do nothing
                    ideal = original;
                case app.KernelConvolutionButton
                    detect = smKern(detect);
                    ideal = smKern((0.1*log(exp(1) + 4.* double(original))));
                case app.CannyFilterButton
                    detect = canny(detect);
                    ideal = canny((0.1*log(exp(1) + 4.* double(original))));
                case app.SobelFilterButton
                    detect = sobel(detect);
                    ideal = sobel((0.1*log(exp(1) + 4.* double(original))));
            end
            
            
            app.Detected_Image = detect;
            imshow(app.Detected_Image, 'parent', app.UIAxes_Detected);
            
            %Display an idealized, noise-free image with edge detection
            imshow(ideal, 'parent', app.UIAxes_Ideal);
            

        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file,~]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file'); %formerly file,path
            app.Original_Image = rgb2gray(imread(file));
            imshow(app.Original_Image, 'parent', app.UIAxes_Original);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options Panel';
            app.UsegpuArrayforHardwareAccelerationSwitchLabel = uilabel(app.OptionsPanel);
            app.UsegpuArrayforHardwareAccelerationSwitchLabel.Text = 'Use gpuArray for Hardware Acceleration';
            app.gpuArraySwitch = uiswitch(app.OptionsPanel, 'slider');
            app.SelectImageButton = uibutton(app.OptionsPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RefreshButton = uibutton(app.OptionsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.RefreshButton.Text = 'Refresh';
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise Panel';
            app.SpeckleNoiseSliderLabel = uilabel(app.NoisePanel);
            app.SpeckleNoiseSliderLabel.Text = 'Speckle Noise';
            app.SpeckleNoiseSlider = uislider(app.NoisePanel);
            app.SpeckleNoiseSlider.Limits = [0 1];
            app.SpeckleNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.SaltPepperNoiseSliderLabel = uilabel(app.NoisePanel);
            app.SaltPepperNoiseSliderLabel.Text = 'Salt & Pepper Noise';
            app.SaltPepperNoiseSlider = uislider(app.NoisePanel);
            app.SaltPepperNoiseSlider.Limits = [0 1];
            app.SaltPepperNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.WhiteUncorrelatedNoiseLabel = uilabel(app.NoisePanel);
            app.WhiteUncorrelatedNoiseLabel.Text = 'White "Uncorrelated" Noise';
            app.WhiteNoiseSlider = uislider(app.NoisePanel);
            app.WhiteNoiseSlider.Limits = [0 1];
            app.WhiteNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.PinkElectronicFlickerNoiseSliderLabel = uilabel(app.NoisePanel);
            app.PinkElectronicFlickerNoiseSliderLabel.Text = 'Pink "Electronic Flicker" Noise';
            app.FlickerNoiseSlider = uislider(app.NoisePanel);
            app.FlickerNoiseSlider.Limits = [0 1000];
            app.FlickerNoiseSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.PoissonNoiseSwitchLabel = uilabel(app.NoisePanel);
            app.PoissonNoiseSwitchLabel.Text = 'Poisson Noise';
            app.PoissonNoiseSwitch = uiswitch(app.NoisePanel, 'slider');
            app.PoissonNoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image Panel';
            app.UIAxes_Original = uiaxes(app.ImagePanel);
            title(app.UIAxes_Original, 'Origninal')
            app.UIAxes_Noisy = uiaxes(app.ImagePanel);
            title(app.UIAxes_Noisy, 'Noisy')
            app.UIAxes_Filtered = uiaxes(app.ImagePanel);
            title(app.UIAxes_Filtered, 'Filtered')
            app.UIAxes_Detected = uiaxes(app.ImagePanel);
            title(app.UIAxes_Detected, 'Feature Detection')
            app.UIAxes_Ideal = uiaxes(app.ImagePanel);
            title(app.UIAxes_Ideal, 'Ideal Feature Detection')
            app.FilteringPanel = uipanel(app.UIFigure);
            app.FilteringPanel.Title = 'Filtering Panel';
            app.MedianFilterSliderLabel = uilabel(app.FilteringPanel);
            app.MedianFilterSliderLabel.Text = 'Median Filter';
            app.MedianFilterSlider = uislider(app.FilteringPanel);
            app.MedianFilterSlider.Limits = [1 16];
            app.MedianFilterSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.MedianFilterSlider.Value = 1;
            app.GaussianBlurSliderLabel = uilabel(app.FilteringPanel);
            app.GaussianBlurSliderLabel.Text = 'Gaussian Blur';
            app.GaussianBlurSlider = uislider(app.FilteringPanel);
            app.GaussianBlurSlider.Limits = [0 10];
            app.GaussianBlurSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.FeatureDetectPanel = uipanel(app.UIFigure);
            app.FeatureDetectPanel.Title = 'Feature-Detect Panel';
            app.EdgeKernelsButtonGroup = uibuttongroup(app.FeatureDetectPanel);
            app.EdgeKernelsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.EdgeKernelsButtonGroup.Title = 'Edge Kernels';
            app.OffButton = uiradiobutton(app.EdgeKernelsButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.KernelConvolutionButton = uiradiobutton(app.EdgeKernelsButtonGroup);
            app.KernelConvolutionButton.Text = 'Kernel Convolution';
            app.CannyFilterButton = uiradiobutton(app.EdgeKernelsButtonGroup);
            app.CannyFilterButton.Text = 'Canny Filter';
            app.SobelFilterButton = uiradiobutton(app.EdgeKernelsButtonGroup);
            app.SobelFilterButton.Text = 'Sobel Filter';
            app.LogarithmicContrastSliderLabel = uilabel(app.FeatureDetectPanel);
            app.LogarithmicContrastSliderLabel.Text = 'Logarithmic Contrast';
            app.LogContrastSlider = uislider(app.FeatureDetectPanel);
            app.LogContrastSlider.Limits = [0 64];
            app.LogContrastSlider.MajorTickLabels = {'Off', '8', '16', '24', '32', '40', '48', '56', '64'};
            app.LogContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
            app.LinearContrastSliderLabel = uilabel(app.FeatureDetectPanel);
            app.LinearContrastSliderLabel.Text = 'Linear Contrast';
            app.ContrastSlider = uislider(app.FeatureDetectPanel);
            app.ContrastSlider.Limits = [0 200];
            app.ContrastSlider.MajorTickLabels = {'Off', '40', '80', '120', '160', '200'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageRefreshFcn, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end